classdef rtMRI_Timepoints_Annotator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        TimepointAnnotationTab    matlab.ui.container.Tab
        UIAxes                    matlab.ui.control.UIAxes
        FilesListBox              matlab.ui.control.ListBox
        choosedirectoryButton     matlab.ui.control.Button
        UIAxes2                   matlab.ui.control.UIAxes
        Slider                    matlab.ui.control.Slider
        onsetButton               matlab.ui.control.Button
        offsetButton              matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        addtotableButton          matlab.ui.control.Button
        exporttableButton         matlab.ui.control.Button
        currentframeSpinnerLabel  matlab.ui.control.Label
        currentframeSpinner       matlab.ui.control.Spinner
        ExamineSelectionTab       matlab.ui.container.Tab
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes4                   matlab.ui.control.UIAxes
        playButton                matlab.ui.control.Button
        Slider_2                  matlab.ui.control.Slider
    end


    properties (Access = public)
        Vr = []; % Description
        Onset % Description
        Offset % Description
        Audio % Description
        OnsetMarkerHandle % Description
        OffsetMarkerHandle % Description
        OnsetLineHandle % Description
        OffsetLineHandle % Description
    end

    properties (Access = private)
    end


    methods (Access = private)

        % Value changed function: FilesListBox
        function FilesListBoxValueChanged(app, event)
            value = app.FilesListBox.Value;
            
            % read in first video frame
            try
                vr = VideoReader(value);
                app.Slider.Limits = [1 round((vr.Duration-1/vr.FrameRate)*vr.FrameRate)];
                app.Slider.Value = 1;
                imshow(vr.readFrame,'Parent',app.UIAxes)
                app.Vr = vr;
                app.Slider.Limits = [1 app.Vr.Duration*app.Vr.FrameRate];
            catch me
                fprintf('Video cannot be opened.\nFile name: %s\n%s\n',...
                    app.FilesListBox.Value,me.message)
            end
            
            % read in audio
            try
                [y,Fs] = audioread(app.FilesListBox.Value);
                t0 = 0;
                tend = length(y)-1;
                time = (t0:tend)./Fs;
                plot(app.UIAxes2,time,y)
                app.UIAxes2.XLim = [t0 tend]./Fs;
                app.UIAxes2.YLim = [min(y) max(y)];
                app.UIAxes2.YTick = [];
                app.UIAxes2.YTickLabel = {};
                app.UIAxes2.XTick = [];
                app.UIAxes2.XTickLabel = {};
                app.Audio = struct('y',y,'Fs',Fs);
            catch me
                fprintf('Video cannot be opened.\nFile name: %s\n%s\n',...
                    app.FilesListBox.Value,me.message)
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            
            if ~isempty(app.Vr)
                app.currentframeSpinner.Value = round(value);
                app.Vr.CurrentTime = value/app.Vr.FrameRate;
                imshow(app.Vr.readFrame,'Parent',app.UIAxes)
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            
            if ~isempty(app.Vr)
                app.currentframeSpinner.Value = round(changingValue);
                app.Vr.CurrentTime = changingValue/app.Vr.FrameRate;
                imshow(app.Vr.readFrame,'Parent',app.UIAxes)
            end
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            app.Vr.CurrentTime = value;
            imshow(readFrame(app.Vr),'Parent',app.UIAxes3)
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.Vr.CurrentTime = changingValue;
            imshow(readFrame(app.Vr),'Parent',app.UIAxes3)
        end

        % Button pushed function: addtotableButton
        function addtotableButtonPushed(app, event)
            file_name = app.FilesListBox.Value;
            lab = '';
            ons = app.Onset;
            offs = app.Offset;
            if ~(isempty(ons) || isempty(offs) || isempty(file_name))
                set(app.UITable, 'Data', cat(1, app.UITable.Data, {file_name, lab, ons, offs}));
            end
        end

        % Button pushed function: choosedirectoryButton
        function choosedirectoryButtonPushed(app, event)
            
            file_dir = uigetdir;
            if file_dir ~= 0
                file_list = dir(fullfile(file_dir,'*.avi'));
                file_list = {file_list.name};
                if ~isempty(file_list)
                    app.FilesListBox.Items = file_list;
                    app.FilesListBox.Items = cellfun(@(x) fullfile(file_dir,x), ...
                        file_list,'UniformOutput',false);
                    app.FilesListBox.Value = app.FilesListBox.Items{1};
                end
            end
        end

        % Value changed function: currentframeSpinner
        function currentframeSpinnerValueChanged(app, event)
            value = app.currentframeSpinner.Value;
            app.Vr.CurrentTime = value/app.Vr.FrameRate;
            imshow(app.Vr.readFrame,'Parent',app.UIAxes)
            app.Slider.Value = value;
        end

        % Value changing function: currentframeSpinner
        function currentframeSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.Vr.CurrentTime = changingValue/app.Vr.FrameRate;
            imshow(app.Vr.readFrame,'Parent',app.UIAxes)
            app.Slider.Value = changingValue;
        end

        % Button pushed function: exporttableButton
        function exporttableButtonPushed(app, event)
            if ~isempty(app.UITable.Data)
                tab = cell2table(app.UITable.Data,'VariableNames',{'file','label','onset','offset'});
                file_name = uiputfile('*.csv','Export CSV','timepoints.csv');
                writetable(tab,file_name);
            end
        end

        % Button pushed function: offsetButton
        function offsetButtonPushed(app, event)
            if ~isempty(app.Vr)
                app.Offset = app.Vr.CurrentTime;
                
                xcoord = app.Vr.CurrentTime;
                ycoord = app.UIAxes2.YLim(2);
                if ~isempty(app.OffsetMarkerHandle)
                    delete(findobj(app.OffsetMarkerHandle))
                    delete(findobj(app.OffsetLineHandle))
                end
                hold(app.UIAxes2,'on')
                app.OffsetMarkerHandle = scatter(app.UIAxes2,xcoord,ycoord,[],'r','v','filled');
                app.OffsetLineHandle = line(app.UIAxes2,[xcoord xcoord],app.UIAxes2.YLim);
                hold(app.UIAxes2,'off')
                
                if ~isempty(app.Onset) && ~isempty(app.Offset) && app.Onset < app.Offset
                    t0 = app.Onset;
                    tend = app.Offset;
                    idx = ceil(t0*app.Audio.Fs):floor(tend*app.Audio.Fs);
                    time = idx/app.Audio.Fs';
                    ysel = app.Audio.y(idx);
                    plot(app.UIAxes4,time,ysel);
                    app.UIAxes4.XLim = [t0 tend];
                    
                    app.Slider_2.Limits = [t0 tend];
                end
            end
        end

        % Button pushed function: onsetButton
        function onsetButtonPushed(app, event)
            if ~isempty(app.Vr)
                app.Onset = app.Vr.CurrentTime;
                
                xcoord = app.Vr.CurrentTime;
                ycoord = app.UIAxes2.YLim(2);
                if ~isempty(app.OnsetMarkerHandle)
                    delete(findobj(app.OnsetMarkerHandle))
                    delete(findobj(app.OnsetLineHandle))
                end
                hold(app.UIAxes2,'on')
                app.OnsetMarkerHandle = scatter(app.UIAxes2,xcoord,ycoord,[],'g','v','filled');
                app.OnsetLineHandle = line(app.UIAxes2,[xcoord xcoord],app.UIAxes2.YLim);
                hold(app.UIAxes2,'off')
                
                if ~isempty(app.Onset) && ~isempty(app.Offset) && app.Onset < app.Offset
                    t0 = app.Onset;
                    tend = app.Offset;
                    idx = ceil(t0*app.Audio.Fs):floor(tend*app.Audio.Fs);
                    time = idx/app.Audio.Fs';
                    ysel = app.Audio.y(idx);
                    plot(app.UIAxes4,time,ysel);
                    app.UIAxes4.XLim = [t0 tend];
                    
                    app.Slider_2.Limits = [t0 tend];
                end
            end
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            
            app.Vr.CurrentTime = app.Onset;
            
            y = app.Audio.y;
            fs = app.Audio.Fs;
            y = y(ceil(app.Onset*fs):floor(app.Offset*fs));
            pl = audioplayer(y,fs);
            play(pl);
            
            while hasFrame(app.Vr) && app.Vr.CurrentTime < app.Offset
                imshow(readFrame(app.Vr),'Parent',app.UIAxes3)
                pause(1/app.Vr.FrameRate)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TimepointAnnotationTab = uitab(app.TabGroup);
            app.TimepointAnnotationTab.Title = 'Timepoint Annotation';
            app.UIAxes = uiaxes(app.TimepointAnnotationTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.FilesListBox = uilistbox(app.TimepointAnnotationTab);
            app.FilesListBox.Items = {};
            app.FilesListBox.ValueChangedFcn = createCallbackFcn(app, @FilesListBoxValueChanged, true);
            app.FilesListBox.Value = {};
            app.choosedirectoryButton = uibutton(app.TimepointAnnotationTab, 'push');
            app.choosedirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @choosedirectoryButtonPushed, true);
            app.choosedirectoryButton.Text = 'choose directory';
            app.UIAxes2 = uiaxes(app.TimepointAnnotationTab);
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.YLim = [0 1];
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.Slider = uislider(app.TimepointAnnotationTab);
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.onsetButton = uibutton(app.TimepointAnnotationTab, 'push');
            app.onsetButton.ButtonPushedFcn = createCallbackFcn(app, @onsetButtonPushed, true);
            app.onsetButton.Text = 'onset';
            app.offsetButton = uibutton(app.TimepointAnnotationTab, 'push');
            app.offsetButton.ButtonPushedFcn = createCallbackFcn(app, @offsetButtonPushed, true);
            app.offsetButton.Text = 'offset';
            app.UITable = uitable(app.TimepointAnnotationTab);
            app.UITable.ColumnName = {'File'; 'Label'; 'Onset'; 'Offset'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.addtotableButton = uibutton(app.TimepointAnnotationTab, 'push');
            app.addtotableButton.ButtonPushedFcn = createCallbackFcn(app, @addtotableButtonPushed, true);
            app.addtotableButton.Text = 'add to table';
            app.exporttableButton = uibutton(app.TimepointAnnotationTab, 'push');
            app.exporttableButton.ButtonPushedFcn = createCallbackFcn(app, @exporttableButtonPushed, true);
            app.exporttableButton.Text = 'export table';
            app.currentframeSpinnerLabel = uilabel(app.TimepointAnnotationTab);
            app.currentframeSpinnerLabel.Text = 'current frame';
            app.currentframeSpinner = uispinner(app.TimepointAnnotationTab);
            app.currentframeSpinner.ValueChangingFcn = createCallbackFcn(app, @currentframeSpinnerValueChanging, true);
            app.currentframeSpinner.Limits = [1 Inf];
            app.currentframeSpinner.ValueChangedFcn = createCallbackFcn(app, @currentframeSpinnerValueChanged, true);
            app.currentframeSpinner.Value = 1;
            app.ExamineSelectionTab = uitab(app.TabGroup);
            app.ExamineSelectionTab.Title = 'Examine Selection';
            app.UIAxes3 = uiaxes(app.ExamineSelectionTab);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.ExamineSelectionTab);
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.playButton = uibutton(app.ExamineSelectionTab, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Text = 'play';
            app.Slider_2 = uislider(app.ExamineSelectionTab);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = rtMRI_Timepoints_Annotator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end